classdef en_ocr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        OptionsLabel                matlab.ui.control.Label
        ClassificationalgorithmButtonGroup  matlab.ui.container.ButtonGroup
        KNearestNeighboursButton    matlab.ui.control.RadioButton
        NaiveBayesButton            matlab.ui.control.RadioButton
        NeighboursDropDownLabel     matlab.ui.control.Label
        NeighboursDropDown          matlab.ui.control.DropDown
        ComponentsDropDownLabel     matlab.ui.control.Label
        ComponentsDropDown          matlab.ui.control.DropDown
        PCASwitchLabel              matlab.ui.control.Label
        PCASwitch                   matlab.ui.control.Switch
        PreprocessingLabel          matlab.ui.control.Label
        ErotionCheckBox             matlab.ui.control.CheckBox
        DilatationCheckBox          matlab.ui.control.CheckBox
        TrainingButton              matlab.ui.control.Button
        ErotionSlider               matlab.ui.control.Slider
        DilatationSlider            matlab.ui.control.Slider
        FillCheckBox                matlab.ui.control.CheckBox
        OpenCheckBox                matlab.ui.control.CheckBox
        OpenSlider                  matlab.ui.control.Slider
        RightPanel                  matlab.ui.container.Panel
        Image_0                     matlab.ui.control.Image
        Image_1                     matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_4                     matlab.ui.control.Image
        Image_5                     matlab.ui.control.Image
        Image_6                     matlab.ui.control.Image
        Image_7                     matlab.ui.control.Image
        Image_8                     matlab.ui.control.Image
        Image_9                     matlab.ui.control.Image
        SingleimagepredictionLabel  matlab.ui.control.Label
        PrediccionLabel             matlab.ui.control.Label
        PanelConfusion              matlab.ui.container.Panel
        RecognitionrateLabel        matlab.ui.control.Label
        RecognitionRateLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dir_trn = '**/d*/*.bmp' % training images directory
        dir_tst = '**/test/*.bmp' % test images directory
        img_trn % Cell for training images
        img_tst % Cell for test images
        y % Classification for each training image
        real % Real classification for test images
        
        tst_pca % Training data processed with PCA
        mdl % Training model
        
        paneles
    end
    
    methods (Access = private)
        
        % Shows test images on their respective panels
        function updateImages(app, imgCell)
            
            [n,~] = size(app.paneles);
            for i = 1:n
                app.paneles{i}.ImageSource = repmat(imgCell{i}, 1, 1, 3);
            end
             
        end
        
        % Calculates the classification of an image
        function predictImage(app, n)
            p = predict(app.mdl,app.tst_pca(n,:));
            app.PrediccionLabel.Text = sprintf('%d',p);
        end
        
        % Highlights one image panel
        function highlight(app,p)
            [n,~] = size(app.paneles);
            for i = 1:n
                set(app.paneles{i}, 'BackgroundColor', 'none');
            end
            if p ~= 0
                set(app.paneles{p}, 'BackgroundColor', [0.64 0.08 0.16]);
            end
        end
        
        % Calculates the training model with a chosen algorithm
        function train(app)
            % convert images to a matrix
            tst = imgCell2Matrix(app.img_tst);
            trn = imgCell2Matrix(app.img_trn);
            
            % apply PCA
            if strcmpi(app.PCASwitch.Value,'on')
                c = app.ComponentsDropDown.Value;
                c = str2double(c);
                [trn,app.tst_pca] = pcompTT(tst,trn,c);
            else
                app.tst_pca = tst;
            end
            
            % apply a chosen recognition algorithm
            switch app.ClassificationalgorithmButtonGroup.SelectedObject.Text
                case app.KNearestNeighboursButton.Text
                    k = app.NeighboursDropDown.Value;
                    k = str2double(k);
                    app.mdl = fitcknn(trn,app.y,'NumNeighbors',k);
                case app.NaiveBayesButton.Text
                    app.mdl = fitcnb(trn,app.y);
            end
        end
        
        % Classifies all images and shows the results with a confusion
        % chart and the recognition rate.
        function predictAll(app)
            % classify all test data based on the training model
            p = predict(app.mdl,app.tst_pca);
            
            % show confusion chart
            confusionchart(app.PanelConfusion,app.real,p);
            
            % calculate and show the recognition rate
            a = app.real-p;
            b = sum(a==0);
            [n,~] = size(app.img_tst);
            tasa = b/n*100;
            app.RecognitionRateLabel.Text = sprintf('%.2f%s', tasa, '%');
        end
        
        % Applies morphological operations to training and test data
        function applyFilter(app)
            % load all data and convert it to cells
            app.img_tst = dir2Cell(app.dir_tst);
            app.img_trn = dir2Cell(app.dir_trn);
            
            % apply filters to training data
            [n,~] = size(app.img_trn);
            tipo = 'disk';
            for i = 1:n
                if app.DilatationCheckBox.Value
                    value = app.DilatationSlider.Value;
                    se = strel(tipo, value);
                    app.img_trn{i} = imdilate(app.img_trn{i},se);
                end
                
                if app.ErotionCheckBox.Value
                    value = app.DilatationSlider.Value;
                    se = strel(tipo,value);
                    app.img_trn{i} = imerode(app.img_trn{i},se);
                end
                
                if app.FillCheckBox.Value
                    app.img_trn{i} = imfill(app.img_trn{i}, 'holes');
                end
                
                if app.OpenCheckBox.Value
                    value = app.OpenSlider.Value;
                    se = strel(tipo,value);
                    app.img_trn{i} = imopen(app.img_trn{i},se);
                end
            end
            
            % apply filters to test data
            [n,~] = size(app.img_tst);
            for i = 1:n
                if app.DilatationCheckBox.Value
                    se = strel('disk',app.DilatationSlider.Value);
                    app.img_tst{i} = imdilate(app.img_tst{i},se);
                end
                
                if app.ErotionCheckBox.Value
                    se = strel('disk',app.ErotionSlider.Value);
                    app.img_tst{i} = imerode(app.img_tst{i},se);
                end
                
                if app.FillCheckBox.Value
                    app.img_tst{i} = imfill(app.img_tst{i}, 'holes');
                end
                
                if app.OpenCheckBox.Value
                    value = app.OpenSlider.Value;
                    se = strel(tipo,value);
                    app.img_tst{i} = imopen(app.img_tst{i},se);
                end
            end
            
            updateImages(app,app.img_tst);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load all data and convert it to cells
            app.img_tst = dir2Cell(app.dir_tst);
            app.img_trn = dir2Cell(app.dir_trn);
            
            % all training images are sorted in ascending order, 10 samples
            % for each number from 0 to 9.
            app.y = repmat(0:9,10,1);
            app.y = app.y(:);
            
            app.real = (0:9)';
            
            % add all image panels to a cell
            app.paneles = cell(10,1);
            app.paneles{1} = app.Image_0;
            app.paneles{2} = app.Image_1;
            app.paneles{3} = app.Image_2;
            app.paneles{4} = app.Image_3;
            app.paneles{5} = app.Image_4;
            app.paneles{6} = app.Image_5;
            app.paneles{7} = app.Image_6;
            app.paneles{8} = app.Image_7;
            app.paneles{9} = app.Image_8;
            app.paneles{10} = app.Image_9;
            
            % update and show test images
            updateImages(app, app.img_tst);
            
            % perform initial training 
            train(app);
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            train(app);
            predictAll(app);
        end

        % Image clicked function: Image_0
        function Image_0Clicked(app, event)
            predictImage(app,1);
            highlight(app,1);
        end

        % Image clicked function: Image_1
        function Image_1Clicked(app, event)
            predictImage(app,2);
            highlight(app,2);
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            predictImage(app,3);
            highlight(app,3);
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            predictImage(app,4);
            highlight(app,4);
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            predictImage(app,5);
            highlight(app,5);
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            predictImage(app,6);
            highlight(app,6);
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            predictImage(app,7);
            highlight(app,7);
        end

        % Image clicked function: Image_7
        function Image_7Clicked(app, event)
            predictImage(app,8);
            highlight(app,8);
        end

        % Image clicked function: Image_8
        function Image_8Clicked(app, event)
            predictImage(app,9);
            highlight(app,9);
        end

        % Image clicked function: Image_9
        function Image_9Clicked(app, event)
            predictImage(app,10);
            highlight(app,10);
        end

        % Callback function
        function PredecirtodoButtonPushed(app, event)
            predictAll(app);
        end

        % Value changed function: ErotionCheckBox
        function ErotionCheckBoxValueChanged(app, event)
            applyFilter(app);
        end

        % Value changed function: DilatationCheckBox
        function DilatationCheckBoxValueChanged(app, event)
            applyFilter(app);
        end

        % Value changed function: FillCheckBox
        function FillCheckBoxValueChanged(app, event)
            applyFilter(app);
        end

        % Value changed function: OpenCheckBox
        function OpenCheckBoxValueChanged(app, event)
            applyFilter(app);
        end

        % Value changed function: ErotionSlider
        function ErotionSliderValueChanged(app, event)
            value = app.ErotionSlider.Value;
            app.ErotionSlider.Value = round(value);
            applyFilter(app);
        end

        % Value changed function: DilatationSlider
        function DilatationSliderValueChanged(app, event)
            value = app.DilatationSlider.Value;
            app.DilatationSlider.Value = round(value);
            applyFilter(app);
        end

        % Value changed function: OpenSlider
        function OpenSliderValueChanged(app, event)
            value = app.OpenSlider.Value;
            app.OpenSlider.Value = round(value);
            applyFilter(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {538, 538};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OptionsLabel = uilabel(app.LeftPanel);
            app.OptionsLabel.Text = 'Options';
            app.ClassificationalgorithmButtonGroup = uibuttongroup(app.LeftPanel);
            app.ClassificationalgorithmButtonGroup.Title = 'Classification algorithm';
            app.KNearestNeighboursButton = uiradiobutton(app.ClassificationalgorithmButtonGroup);
            app.KNearestNeighboursButton.Text = 'K-Nearest Neighbours';
            app.KNearestNeighboursButton.Value = true;
            app.NaiveBayesButton = uiradiobutton(app.ClassificationalgorithmButtonGroup);
            app.NaiveBayesButton.Text = 'Naive Bayes';
            app.NeighboursDropDownLabel = uilabel(app.LeftPanel);
            app.NeighboursDropDownLabel.Text = 'Neighbours';
            app.NeighboursDropDown = uidropdown(app.LeftPanel);
            app.NeighboursDropDown.Items = {'1', '3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23'};
            app.NeighboursDropDown.Value = '1';
            app.ComponentsDropDownLabel = uilabel(app.LeftPanel);
            app.ComponentsDropDownLabel.Text = 'Components';
            app.ComponentsDropDown = uidropdown(app.LeftPanel);
            app.ComponentsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ComponentsDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ComponentsDropDown.Value = '1';
            app.PCASwitchLabel = uilabel(app.LeftPanel);
            app.PCASwitchLabel.Text = 'PCA';
            app.PCASwitch = uiswitch(app.LeftPanel, 'slider');
            app.PCASwitch.Value = 'On';
            app.PreprocessingLabel = uilabel(app.LeftPanel);
            app.PreprocessingLabel.Text = 'Preprocessing';
            app.ErotionCheckBox = uicheckbox(app.LeftPanel);
            app.ErotionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErotionCheckBoxValueChanged, true);
            app.ErotionCheckBox.Text = 'Erotion';
            app.DilatationCheckBox = uicheckbox(app.LeftPanel);
            app.DilatationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DilatationCheckBoxValueChanged, true);
            app.DilatationCheckBox.Text = 'Dilatation';
            app.TrainingButton = uibutton(app.LeftPanel, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.ErotionSlider = uislider(app.LeftPanel);
            app.ErotionSlider.Limits = [1 5];
            app.ErotionSlider.MajorTicks = [1 2 3 4 5];
            app.ErotionSlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.ErotionSlider.ValueChangedFcn = createCallbackFcn(app, @ErotionSliderValueChanged, true);
            app.ErotionSlider.MinorTicks = [];
            app.ErotionSlider.Value = 1;
            app.DilatationSlider = uislider(app.LeftPanel);
            app.DilatationSlider.Limits = [1 7];
            app.DilatationSlider.MajorTicks = [1 2 3 4 5 6 7];
            app.DilatationSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7'};
            app.DilatationSlider.ValueChangedFcn = createCallbackFcn(app, @DilatationSliderValueChanged, true);
            app.DilatationSlider.MinorTicks = [];
            app.DilatationSlider.Value = 1;
            app.FillCheckBox = uicheckbox(app.LeftPanel);
            app.FillCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillCheckBoxValueChanged, true);
            app.FillCheckBox.Text = 'Fill';
            app.OpenCheckBox = uicheckbox(app.LeftPanel);
            app.OpenCheckBox.ValueChangedFcn = createCallbackFcn(app, @OpenCheckBoxValueChanged, true);
            app.OpenCheckBox.Text = 'Open';
            app.OpenSlider = uislider(app.LeftPanel);
            app.OpenSlider.Limits = [1 4];
            app.OpenSlider.MajorTicks = [1 2 3 4];
            app.OpenSlider.MajorTickLabels = {'1', '2', '3', '4'};
            app.OpenSlider.ValueChangedFcn = createCallbackFcn(app, @OpenSliderValueChanged, true);
            app.OpenSlider.MinorTicks = [];
            app.OpenSlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_0 = uiimage(app.RightPanel);
            app.Image_0.ImageClickedFcn = createCallbackFcn(app, @Image_0Clicked, true);
            app.Image_1 = uiimage(app.RightPanel);
            app.Image_1.ImageClickedFcn = createCallbackFcn(app, @Image_1Clicked, true);
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_3 = uiimage(app.RightPanel);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_4 = uiimage(app.RightPanel);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_5 = uiimage(app.RightPanel);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_6 = uiimage(app.RightPanel);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_7 = uiimage(app.RightPanel);
            app.Image_7.ImageClickedFcn = createCallbackFcn(app, @Image_7Clicked, true);
            app.Image_8 = uiimage(app.RightPanel);
            app.Image_8.ImageClickedFcn = createCallbackFcn(app, @Image_8Clicked, true);
            app.Image_9 = uiimage(app.RightPanel);
            app.Image_9.ImageClickedFcn = createCallbackFcn(app, @Image_9Clicked, true);
            app.SingleimagepredictionLabel = uilabel(app.RightPanel);
            app.SingleimagepredictionLabel.Text = 'Single image prediction';
            app.PrediccionLabel = uilabel(app.RightPanel);
            app.PrediccionLabel.Text = 'N';
            app.PanelConfusion = uipanel(app.RightPanel);
            app.PanelConfusion.Title = 'Confusion chart';
            app.RecognitionrateLabel = uilabel(app.RightPanel);
            app.RecognitionrateLabel.Text = 'Recognition rate';
            app.RecognitionRateLabel = uilabel(app.RightPanel);
            app.RecognitionRateLabel.Text = 'N/A%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = en_ocr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end